classdef DomBot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LastCommandTextAreaLabel  matlab.ui.control.Label
        LastCommandTextArea       matlab.ui.control.TextArea
        DomBotLabel               matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        SimulationButton          matlab.ui.control.StateButton
        CLIDEBUGButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        sim % simulation object
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SimulationButton
        function SimulationButtonValueChanged(app, event)
            value = app.SimulationButton.Value;
            if(value)
                app.sim = Simulation(app.CLIDEBUGButton.Value);                                 % Call constructor with debug button value
                cliOutput = [datestr(now,'HH:MM:SS'),' [APP] Simulation Constructor Called'];   % Set CLI Output
                if(app.CLIDEBUGButton.Value); disp(cliOutput); end                                % Display in command window
                app.LastCommandTextArea.Value = cliOutput;                                      % Display in app
            else    
                delete(app.sim);                                                                % Call destructor
                cliOutput = [datestr(now,'HH:MM:SS'),' [APP] Simulation Deconstructor Called']; % Set CLI Output
                if(app.CLIDEBUGButton.Value); disp(cliOutput); end                                                             % Display in command window
                app.LastCommandTextArea.Value = cliOutput;                                      % Display in app
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LastCommandTextAreaLabel = uilabel(app.UIFigure);
            app.LastCommandTextAreaLabel.Text = 'Last Command';
            app.LastCommandTextArea = uitextarea(app.UIFigure);
            app.DomBotLabel = uilabel(app.UIFigure);
            app.DomBotLabel.Text = 'DomBot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Koda Enoka - 12580748 | Nathanael Gandhi - 12966382 | Travis Goodshaw - 12912770';
            app.SimulationButton = uibutton(app.UIFigure, 'state');
            app.SimulationButton.ValueChangedFcn = createCallbackFcn(app, @SimulationButtonValueChanged, true);
            app.SimulationButton.Text = 'Simulation';
            app.CLIDEBUGButton = uibutton(app.UIFigure, 'state');
            app.CLIDEBUGButton.Text = 'CLI DEBUG';
            app.CLIDEBUGButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DomBot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end